classdef DEApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NpEditFieldLabel         matlab.ui.control.Label
        NpEditField              matlab.ui.control.NumericEditField
        FEditFieldLabel          matlab.ui.control.Label
        FEditField               matlab.ui.control.NumericEditField
        mute_rateEditFieldLabel  matlab.ui.control.Label
        mute_rateEditField       matlab.ui.control.NumericEditField
        runButton                matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        N_paraEditFieldLabel     matlab.ui.control.Label
        N_paraEditField          matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function initial(app)
            global Np; 
            global F;  
            global mute_rate;
            global N_para;
            global curve;
            Np=30;F=0.8; mute_rate=0.5;N_para=225;
            curve=[1,1];
            plot(app.UIAxes,[1 2 3 4],'-r');
            hold(app.UIAxes);
        end

        % Value changed function: NpEditField
        function ChangeNp(app, event)
            value = app.NpEditField.Value;
            global Np;
            Np=value;
        end

        % Value changed function: FEditField
        function changeF(app, event)
            value = app.FEditField.Value;
            global F;
            F=value;
        end

        % Value changed function: mute_rateEditField
        function ChangeMute_rate(app, event)
            value = app.mute_rateEditField.Value;
            global mute_rate;
            mute_rate=value;
        end

        % Value changed function: N_paraEditField
        function ChangeN_para(app, event)
            value = app.N_paraEditField.Value;
            global N_para;
            N_para=value;
        end

        % Button pushed function: runButton
        function domain(app, event)
            global Np; 
            global F;  
            global mute_rate;
            global N_para;
            global curve;
            curve=main(Np,F,mute_rate,N_para);
            plot(app.UIAxes,curve(:,1),curve(:,2),'-r');
        end

        % Size changed function: UIFigure
        function huatu2(app, event)
            position = app.UIFigure.Position;
            global curve;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @huatu2, true);
            setAutoResize(app, app.UIFigure, true)
            app.NpEditFieldLabel = uilabel(app.UIFigure);
            app.NpEditFieldLabel.Text = 'Np';
            app.NpEditField = uieditfield(app.UIFigure, 'numeric');
            app.NpEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeNp, true);
            app.NpEditField.Value = 30;
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.UIFigure, 'numeric');
            app.FEditField.ValueChangedFcn = createCallbackFcn(app, @changeF, true);
            app.FEditField.Value = 1;
            app.mute_rateEditFieldLabel = uilabel(app.UIFigure);
            app.mute_rateEditFieldLabel.Text = 'mute_rate';
            app.mute_rateEditField = uieditfield(app.UIFigure, 'numeric');
            app.mute_rateEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeMute_rate, true);
            app.mute_rateEditField.Value = 0.5;
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @domain, true);
            app.runButton.Text = 'run';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'generation');
            ylabel(app.UIAxes, 'fitness');
            app.N_paraEditFieldLabel = uilabel(app.UIFigure);
            app.N_paraEditFieldLabel.Text = 'N_para';
            app.N_paraEditField = uieditfield(app.UIFigure, 'numeric');
            app.N_paraEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeN_para, true);
            app.N_paraEditField.Value = 225;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DEApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initial)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end